classdef NotchedDesigner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NotchedTubeDesignerUIFigure  matlab.ui.Figure
        NotchedTubeDesignerLabel     matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        SimulationTab                matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        LoadSTLFileLabel             matlab.ui.control.Label
        LoadFileButton               matlab.ui.control.Button
        OutputNotchedTubeParametersLabel  matlab.ui.control.Label
        OutputParametersButton       matlab.ui.control.Button
        meshID                       matlab.ui.control.EditField
        SimulationStatisticsPanel    matlab.ui.container.Panel
        Statistic1                   matlab.ui.control.Label
        Statistic2                   matlab.ui.control.Label
        Statistic3                   matlab.ui.control.Label
        Statistic4                   matlab.ui.control.Label
        Statistic5                   matlab.ui.control.Label
        Statistic6                   matlab.ui.control.Label
        Statistic7                   matlab.ui.control.Label
        Statistic8                   matlab.ui.control.Label
        PlotContainer                matlab.ui.container.Panel
        PlotAxes                     matlab.ui.control.UIAxes
        ErrorPanel                   matlab.ui.container.Panel
        ErrorLabel                   matlab.ui.control.Label
        DesignTab                    matlab.ui.container.Tab
        NotchParametersPanel         matlab.ui.container.Panel
        GridLayout                   matlab.ui.container.GridLayout
        AlphaLabel                   matlab.ui.control.Label
        WidthLabel                   matlab.ui.control.Label
        HeightLabel                  matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        LengthLabel                  matlab.ui.control.Label
        EditField_2                  matlab.ui.control.NumericEditField
        EditField_3                  matlab.ui.control.NumericEditField
        EditField_4                  matlab.ui.control.NumericEditField
        EditField_5                  matlab.ui.control.NumericEditField
        EditField_6                  matlab.ui.control.NumericEditField
        PositionLabel                matlab.ui.control.Label
        NumberofNotchesLabel         matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        DeleteButton                 matlab.ui.control.Button
        NotchedPlotContainer         matlab.ui.container.Panel
        NotchedAxes                  matlab.ui.control.UIAxes
        WPIlogo                      matlab.ui.control.Image
        cometlablogo                 matlab.ui.control.Image
    end

    
    properties (Access = public)
        
        % The STL file currently loaded
        stlFileName; % The name of the uploaded .STL file
        stlFilePath; % The path of the uploaded .STL file
        meMesh; % Middle-ear Mesh
        osMesh; % Ossicle Mesh
        transform; % Transform from Mesh to Base
        
        % Looping Flags
        stopFlag = 0; % Check whether the loop needs to be stopped
        
        % Robot configuration parameters
        robotDisplacement = 0.1;
        robotRotation = 0.0;
        robotAdvancement = 2.0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: EditField, EditField_2, EditField_3, 
        % EditField_4, EditField_5, EditField_6, SaveButton
        function updateNotched(app, event)
            value = app.EditField.Value;
            % TODO: There has been a change to the notched tube robot,
            % update it
        end

        % Button pushed function: DeleteButton
        function removeNotch(app, event)
            % TODO: Remove a notch from the tube
        end

        % Button pushed function: LoadFileButton
        function loadSTL(app, event)
            % [app.stlFileName, app.stlFilePath] = uigetfile;
            % fprintf("Filename: %s, Filepath: %s \n",app.stlFileName, app.stlFilePath);
            cla(app.PlotAxes);
            value = app.meshID.Value;
            fprintf("Loading: %s\n ", value);
            if strlength(value) > 0
                [meModel, transform, meModelRayCast, meSegmentation] = loadEarModel(value);
                x_trans = transform(1, 4);
                y_trans = transform(2, 4);
                z_trans = transform(3, 4);
                app.transform = transform;
                disp(transform);
                app.meMesh = meModel.me;
                app.osMesh = meModel.os;
                patch(app.PlotAxes, app.meMesh, 'FaceAlpha', 0.5, 'FaceColor', '#2b2e33');
                patch(app.PlotAxes, app.osMesh, 'LineWidth', 0.2, 'FaceColor', '#071938');
                campos(app.PlotAxes, 'auto');
                xlim(app.PlotAxes, 'auto');
                ylim(app.PlotAxes, 'auto');
                zlim(app.PlotAxes, 'auto');
                axis(app.PlotAxes, 'equal');
                
            else
                disp("Illegal filename: Filename cannot be empty")
            end
            
        end

        % Callback function
        function outputParameters(app, event)
            % TODO: Output all the parameters of the concentric notched
            % tube
        end

        % Button pushed function: OutputParametersButton
        function stopLoop(app, event)
            app.stopFlag = 1;
        end

        % Key press function: NotchedTubeDesignerUIFigure
        function NotchedTubeDesignerUIFigureKeyPress(app, event)
            % Pass the callback through to another function
            
            % Execute appKeyPressCallBack.m
            key = event.Key;
            switch key
                case 'w'
                    % forward, W key is pressed
                    app.robotAdvancement = app.robotAdvancement + 0.1;
                case 's'
                    % backward, S key is pressed
                    app.robotAdvancement = app.robotAdvancement - 0.1;
                case 'a'
                    % left, A key is pressed
                    app.robotDisplacement = app.robotDisplacement + 0.1;
                case 'd'
                    % right, d key is pressed
                    app.robotDisplacement = app.robotDisplacement - 0.1;
                case 'q'
                    app.robotRotation = app.robotRotation + 0.1;
                case 'e'
                    app.robotRotation = app.robotRotation - 0.1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NotchedTubeDesignerUIFigure = uifigure('Visible', 'off');
            app.NotchedTubeDesignerUIFigure.Name = 'Notched Tube Designer';
            app.NotchedTubeDesignerUIFigure.KeyPressFcn = createCallbackFcn(app, @NotchedTubeDesignerUIFigureKeyPress, true);
            app.NotchedTubeDesignerLabel = uilabel(app.NotchedTubeDesignerUIFigure);
            app.NotchedTubeDesignerLabel.Text = 'Notched Tube Designer';
            app.TabGroup = uitabgroup(app.NotchedTubeDesignerUIFigure);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Panel = uipanel(app.SimulationTab);
            app.LoadSTLFileLabel = uilabel(app.Panel);
            app.LoadSTLFileLabel.Text = 'Load .STL File';
            app.LoadFileButton = uibutton(app.Panel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @loadSTL, true);
            app.LoadFileButton.Text = 'Load File';
            app.OutputNotchedTubeParametersLabel = uilabel(app.Panel);
            app.OutputNotchedTubeParametersLabel.Text = 'Output Notched Tube Parameters';
            app.OutputParametersButton = uibutton(app.Panel, 'push');
            app.OutputParametersButton.ButtonPushedFcn = createCallbackFcn(app, @stopLoop, true);
            app.OutputParametersButton.Text = 'Output Parameters';
            app.meshID = uieditfield(app.Panel, 'text');
            app.meshID.Value = 'Mesh ID';
            app.SimulationStatisticsPanel = uipanel(app.SimulationTab);
            app.SimulationStatisticsPanel.Title = 'Simulation Statistics';
            app.Statistic1 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic1.Text = 'Statistic 1';
            app.Statistic2 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic2.Text = 'Statistic 2';
            app.Statistic3 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic3.Text = 'Statistic 1';
            app.Statistic4 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic4.Text = 'Statistic 2';
            app.Statistic5 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic5.Text = 'Statistic 1';
            app.Statistic6 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic6.Text = 'Statistic 2';
            app.Statistic7 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic7.Text = 'Statistic 1';
            app.Statistic8 = uilabel(app.SimulationStatisticsPanel);
            app.Statistic8.Text = 'Statistic 2';
            app.PlotContainer = uipanel(app.SimulationTab);
            app.PlotAxes = uiaxes(app.PlotContainer);
            title(app.PlotAxes, '')
            xlabel(app.PlotAxes, 'X')
            ylabel(app.PlotAxes, 'Y')
            app.PlotAxes.XLim = [-15 15];
            app.PlotAxes.YLim = [-15 15];
            app.PlotAxes.ZLim = [0 15];
            app.ErrorPanel = uipanel(app.SimulationTab);
            app.ErrorPanel.Visible = 'off';
            app.ErrorLabel = uilabel(app.ErrorPanel);
            app.ErrorLabel.Text = '';
            app.DesignTab = uitab(app.TabGroup);
            app.DesignTab.Title = 'Design';
            app.NotchParametersPanel = uipanel(app.DesignTab);
            app.NotchParametersPanel.Title = 'Notch Parameters';
            app.GridLayout = uigridlayout(app.NotchParametersPanel);
            app.AlphaLabel = uilabel(app.GridLayout);
            app.AlphaLabel.Text = 'Alpha:';
            app.WidthLabel = uilabel(app.GridLayout);
            app.WidthLabel.Text = 'Width:';
            app.HeightLabel = uilabel(app.GridLayout);
            app.HeightLabel.Text = 'Height:';
            app.EditField = uieditfield(app.GridLayout, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @updateNotched, true);
            app.LengthLabel = uilabel(app.GridLayout);
            app.LengthLabel.Text = 'Length:';
            app.EditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @updateNotched, true);
            app.EditField_3 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @updateNotched, true);
            app.EditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @updateNotched, true);
            app.EditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @updateNotched, true);
            app.EditField_6 = uieditfield(app.GridLayout, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @updateNotched, true);
            app.PositionLabel = uilabel(app.GridLayout);
            app.PositionLabel.Text = 'Position:';
            app.NumberofNotchesLabel = uilabel(app.GridLayout);
            app.NumberofNotchesLabel.Text = 'Number of Notches:';
            app.SaveButton = uibutton(app.GridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @updateNotched, true);
            app.SaveButton.Text = 'Save';
            app.DeleteButton = uibutton(app.GridLayout, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @removeNotch, true);
            app.DeleteButton.Text = 'Delete';
            app.NotchedPlotContainer = uipanel(app.DesignTab);
            app.NotchedAxes = uiaxes(app.NotchedPlotContainer);
            title(app.NotchedAxes, 'Title')
            xlabel(app.NotchedAxes, 'X')
            ylabel(app.NotchedAxes, 'Y')
            app.WPIlogo = uiimage(app.NotchedTubeDesignerUIFigure);
            app.WPIlogo.ImageSource = 'wpi.png';
            app.cometlablogo = uiimage(app.NotchedTubeDesignerUIFigure);
            app.cometlablogo.ImageSource = 'cometlab.PNG';
            % Show the figure after all components are created
            app.NotchedTubeDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NotchedDesigner

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NotchedTubeDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NotchedTubeDesignerUIFigure)
        end
    end
end