classdef synthesisapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WristDesignerUIFigure          matlab.ui.Figure
        VirtualAnatomyPanel            matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        CurveDesignTab                 matlab.ui.container.Tab
        GridLayout2                    matlab.ui.container.GridLayout
        ArcLengthmmSliderLabel         matlab.ui.control.Label
        ArcLengthmmSlider              matlab.ui.control.Slider
        BendingRadiusmmSliderLabel     matlab.ui.control.Label
        BendingRadiusmmSlider          matlab.ui.control.Slider
        Torsion1mmSliderLabel          matlab.ui.control.Label
        Torsion1mmSlider               matlab.ui.control.Slider
        SelectcurveDropDownLabel       matlab.ui.control.Label
        SelectcurveDropDown            matlab.ui.control.DropDown
        DisplayTubeButton              matlab.ui.control.StateButton
        DeleteCurrentCurveButton       matlab.ui.control.Button
        TubeThicknessmmEditFieldLabel  matlab.ui.control.Label
        TubeThicknessmmEditField       matlab.ui.control.NumericEditField
        WristSynthesisTab              matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        LoadSTLModelButton             matlab.ui.control.Button
        EraseSTLModelButton            matlab.ui.control.Button
        EnableRayCastingButton         matlab.ui.control.StateButton
        STLDropDown                    matlab.ui.control.DropDown
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % The following properties store the curve designed by the user
        curves;       % List of individual curves - each of these is returned by the `makecurve' function
        curvesCount;  % Total number of curves - integer
        currentCurve; % Curve currently selected by the user - integer
        %entireCurve;  % All the curves stacked together
        
        meMesh; % Anatomical Model of the middle ear
        osMesh; % Anatomical Model of the ossicles
        meModelLoaded; % Is an anatomical model being displayed? - boolean
        availableModels; % List of available stl anatomical models - cell array of strings
        Tee_in_base;  % Transformation between origin of the curve and end effector
        TCT_in_base;  % Transformation between the origin of the curve and the space of the anatomical model
    end
    
    methods (Access = private)
        
        function addCurve(app)
            % This function creates a curve based on the parameters set by
            % the user and updates app.curvesCount and app.currentCurve
            % accordingly
            app.curvesCount = app.curvesCount + 1;
            app.currentCurve = app.curvesCount;
            arcLength = app.ArcLengthmmSlider.Value * 1e-3;
            curvature = 1 / (app.BendingRadiusmmSlider.Value * 1e-3);
            curvatureFcn = @(s,arcLength) curvature .* ones(1,length(s));
            app.curves{app.curvesCount} = makecurve(arcLength, curvatureFcn);
        end
        
        function deleteCurve(app)
            % This function deletes the curve selected by the user
            % and updates app.curvesCount and app.currentCurve
            % accordingly
            for ii = fliplr(app.currentCurve : app.curvesCount)
                app.curves{ii} = [];
            end
            
            app.curvesCount = app.currentCurve - 1;
            app.currentCurve = app.curvesCount;
        end
        
        function initializeAnatomyModels(app)
            app.availableModels = {'atlas', '175', '176', '179', '183', '184'};
            app.STLDropDown.Items = app.availableModels;
            app.meModelLoaded = false;   
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Create the first curve
            app.curvesCount = 0;
            app.addCurve();
            
            % Popoulate the list of available anatomy models
            app.initializeAnatomyModels();
        end

        % Value changed function: SelectcurveDropDown
        function SelectcurveDropDownValueChanged(app, event)
            % Invokes the addCurve function and updates the dropdown menu
            % accordingly
            value = app.SelectcurveDropDown.Value;
            
            if strcmp(value, 'Add')
                app.addCurve(); % this updates `app.currentCurve' and `app.curves'
                app.SelectcurveDropDown.Items{end} = num2str(app.currentCurve);
                app.SelectcurveDropDown.Items{end+1} = 'Add';
                app.SelectcurveDropDown.Value = num2str(app.currentCurve);
            else
                app.currentCurve = value;
                app.SelectcurveDropDown.Value = num2str(app.currentCurve);
            end
        end

        % Button pushed function: DeleteCurrentCurveButton
        function DeleteCurrentCurveButtonPushed(app, event)
            
            if app.currentCurve == 1
               f = app.WristDesignerUIFigure;
               uialert(f,'The first curve cannot be eliminated','Invalid Action');
               return;
            end
            
            app.deleteCurve(); % this updates `app.currentCurve' and `app.curves'
            %disp(app.currentCurve);
            app.SelectcurveDropDown.Items{end-2} = num2str(app.currentCurve);
            app.SelectcurveDropDown.Items{end-1} = 'Add';
            app.SelectcurveDropDown.Items(end) = [];
            app.SelectcurveDropDown.Value = num2str(app.currentCurve);
        end

        % Button pushed function: LoadSTLModelButton
        function LoadSTLModelButtonPushed(app, event)
 
            modelID = app.STLDropDown.Value;
                        
            try
                pathMe = fullfile('anatomical-models', modelID, 'me.mesh');
                pathOs = fullfile('anatomical-models', modelID, 'ossicle.mesh');
                app.meMesh = meshread(pathMe);
                app.osMesh = meshread(pathOs);
                app.meModelLoaded = true;
            catch exception
               f = app.WristDesignerUIFigure;
               uialert(f, exception.message, 'Failed to Read STL Model');
            end
            
        end

        % Button pushed function: EraseSTLModelButton
        function EraseSTLModelButtonPushed(app, event)
            if app.meModelLoaded == false
                f = app.WristDesignerUIFigure;
                uialert(f, 'No anatomy models is currently loaded.', 'Invalid Action');
                return;
            end
            
            app.meMesh = [];
            app.osMesh = [];
            app.meModelLoaded = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WristDesignerUIFigure = uifigure('Visible', 'off');
            app.WristDesignerUIFigure.Name = 'Wrist Designer';
            app.VirtualAnatomyPanel = uipanel(app.WristDesignerUIFigure);
            app.VirtualAnatomyPanel.Title = 'Virtual Anatomy';
            app.GridLayout = uigridlayout(app.VirtualAnatomyPanel);
            app.STLDropDown = uidropdown(app.GridLayout);
            app.LoadSTLModelButton = uibutton(app.GridLayout, 'push');
            app.LoadSTLModelButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSTLModelButtonPushed, true);
            app.LoadSTLModelButton.Text = 'Load STL Model';
            app.EraseSTLModelButton = uibutton(app.GridLayout, 'push');
            app.EraseSTLModelButton.ButtonPushedFcn = createCallbackFcn(app, @EraseSTLModelButtonPushed, true);
            app.EraseSTLModelButton.Text = 'Erase STL Model';
            app.EnableRayCastingButton = uibutton(app.GridLayout, 'state');
            app.EnableRayCastingButton.Text = 'Enable Ray Casting';
            app.UIAxes = uiaxes(app.WristDesignerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.TabGroup = uitabgroup(app.WristDesignerUIFigure);
            app.CurveDesignTab = uitab(app.TabGroup);
            app.CurveDesignTab.Title = 'Curve Design';
            app.GridLayout2 = uigridlayout(app.CurveDesignTab);
            app.ArcLengthmmSliderLabel = uilabel(app.GridLayout2);
            app.ArcLengthmmSliderLabel.Text = 'Arc Length (mm)';
            app.ArcLengthmmSlider = uislider(app.GridLayout2);
            app.ArcLengthmmSlider.Limits = [1 30];
            app.ArcLengthmmSlider.MajorTicks = [1 5 10 15 20 25 30 35 40 45 50];
            app.ArcLengthmmSlider.Value = 1;
            app.BendingRadiusmmSliderLabel = uilabel(app.GridLayout2);
            app.BendingRadiusmmSliderLabel.Text = 'Bending Radius (mm)';
            app.BendingRadiusmmSlider = uislider(app.GridLayout2);
            app.BendingRadiusmmSlider.Limits = [5 65];
            app.BendingRadiusmmSlider.Value = 65;
            app.Torsion1mmSliderLabel = uilabel(app.GridLayout2);
            app.Torsion1mmSliderLabel.Text = 'Torsion (1/mm)';
            app.Torsion1mmSlider = uislider(app.GridLayout2);
            app.SelectcurveDropDownLabel = uilabel(app.GridLayout2);
            app.SelectcurveDropDownLabel.Text = 'Select curve';
            app.SelectcurveDropDown = uidropdown(app.GridLayout2);
            app.SelectcurveDropDown.Items = {'1', 'Add'};
            app.SelectcurveDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectcurveDropDownValueChanged, true);
            app.SelectcurveDropDown.Value = '1';
            app.DisplayTubeButton = uibutton(app.GridLayout2, 'state');
            app.DisplayTubeButton.Text = 'Display Tube';
            app.DeleteCurrentCurveButton = uibutton(app.GridLayout2, 'push');
            app.DeleteCurrentCurveButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentCurveButtonPushed, true);
            app.DeleteCurrentCurveButton.Text = 'Delete Current Curve';
            app.TubeThicknessmmEditFieldLabel = uilabel(app.GridLayout2);
            app.TubeThicknessmmEditFieldLabel.Text = 'Tube Thickness (mm)';
            app.TubeThicknessmmEditField = uieditfield(app.GridLayout2, 'numeric');
            app.TubeThicknessmmEditField.Value = 1.4;
            app.WristSynthesisTab = uitab(app.TabGroup);
            app.WristSynthesisTab.Title = 'Wrist Synthesis';
            % Show the figure after all components are created
            app.WristDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synthesisapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WristDesignerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WristDesignerUIFigure)
        end
    end
end